classdef GUI_Classify < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SelectthemovementyouwanttoclassifyLabel  matlab.ui.control.Label
        StaticPositionsButton      matlab.ui.control.Button
        MovementButton             matlab.ui.control.Button
        PushupsorliftingButton     matlab.ui.control.Button
        JustCreatedExerciseButton  matlab.ui.control.Button
        GotoMainMenuButton         matlab.ui.control.Button
        Label_4                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StaticPositionsButton
        function StaticPositionsButtonPushed(app, event)
            assignin('base',"choise",1)
            UIFigureCloseRequest(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: PushupsorliftingButton
        function PushupsorliftingButtonPushed(app, event)
            assignin('base',"choise",3)
            UIFigureCloseRequest(app)
        end

        % Button pushed function: JustCreatedExerciseButton
        function JustCreatedExerciseButtonPushed(app, event)
            assignin('base',"choise",4)
            UIFigureCloseRequest(app)
        end

        % Button pushed function: MovementButton
        function MovementButtonPushed(app, event)
                assignin('base',"choise",2)  
                UIFigureCloseRequest(app)
        end

        % Button pushed function: GotoMainMenuButton
        function GotoMainMenuButtonPushed(app, event)
            UIFigureCloseRequest(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SelectthemovementyouwanttoclassifyLabel = uilabel(app.UIFigure);
            app.SelectthemovementyouwanttoclassifyLabel.Text = 'Select the movement you want to classify';
            app.StaticPositionsButton = uibutton(app.UIFigure, 'push');
            app.StaticPositionsButton.ButtonPushedFcn = createCallbackFcn(app, @StaticPositionsButtonPushed, true);
            app.StaticPositionsButton.Text = 'Static Positions';
            app.MovementButton = uibutton(app.UIFigure, 'push');
            app.MovementButton.ButtonPushedFcn = createCallbackFcn(app, @MovementButtonPushed, true);
            app.MovementButton.Text = 'Movement';
            app.PushupsorliftingButton = uibutton(app.UIFigure, 'push');
            app.PushupsorliftingButton.ButtonPushedFcn = createCallbackFcn(app, @PushupsorliftingButtonPushed, true);
            app.PushupsorliftingButton.Text = 'Pushups or lifting';
            app.JustCreatedExerciseButton = uibutton(app.UIFigure, 'push');
            app.JustCreatedExerciseButton.ButtonPushedFcn = createCallbackFcn(app, @JustCreatedExerciseButtonPushed, true);
            app.JustCreatedExerciseButton.Text = 'Just Created Exercise';
            app.GotoMainMenuButton = uibutton(app.UIFigure, 'push');
            app.GotoMainMenuButton.ButtonPushedFcn = createCallbackFcn(app, @GotoMainMenuButtonPushed, true);
            app.GotoMainMenuButton.Text = 'Go to Main Menu';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'This pre created exercise permites '; 'you to identify movement with or '; '            without lifting weight '};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'This pre created exercise permites '; 'you to identify static position with '; '        lifting weight or without it '};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {'This pre created exercise permites '; 'you to identify if the user is doing '; '      pushups or lifting weight'};
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = {'This funcion enables you to visualize '; 'the result of the exercise you created'; 'In order to use this function first you '; '      have to create an exercise.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Classify

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end