classdef GUI_Create_Options < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CreatingTheExerciseOptionsLabel  matlab.ui.control.Label
        InsertEditField            matlab.ui.control.EditField
        InsertTrainingOptionLabel  matlab.ui.control.Label
        InordertoTrainthecodeyoufirstneedtocreatetheLabel  matlab.ui.control.Label
        InsertinthefieldtheoptionandclickonaccepttosaveitLabel  matlab.ui.control.Label
        OptionsselectedLabel       matlab.ui.control.Label
        ListBox                    matlab.ui.control.ListBox
        AddoptionButton            matlab.ui.control.StateButton
        GotoMainmenuButton         matlab.ui.control.StateButton
        StartTakingSamplesButton   matlab.ui.control.StateButton
        InordertotrainthecodeyoufirstneedtoaddanexerciseoptionLabel  matlab.ui.control.Label
        WARNINGLabel               matlab.ui.control.Label
    end

    
    properties (Access = public)
        y=1;
        x=1;
        valores = 0;% Description
        entra = 0;
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, argument)
                app.valores = 0;
                app.y=1;
                app.ListBox.Items = {''};
                app.valores = argument;
                app.InordertotrainthecodeyoufirstneedtoaddanexerciseoptionLabel.Visible = 'off';
                app.WARNINGLabel.Visible = 'off';
        end

        % Value changed function: AddoptionButton
        function AddoptionButtonValueChanged(app, event)
            if (app.InsertEditField.Value ~= "")
                app.valores{app.y,1} = app.InsertEditField.Value;
                app.y = app.y + 1;
                app.ListBox.Items = app.valores;
                app.InsertEditField.Value = '';
            end
        end

        % Value changed function: StartTakingSamplesButton
        function StartTakingSamplesButtonValueChanged(app, event)
            if app.y > 1
                assignin('base',"state_positions",app.valores);
                assignin('base',"length_state",length(app.valores))
                assignin('base',"state",categorical(app.valores))
                assignin('base',"choise",1)
                app.InordertotrainthecodeyoufirstneedtoaddanexerciseoptionLabel.Visible = 'off';
                app.WARNINGLabel.Visible = 'off';
                UIFigureCloseRequest(app, event);
            else
                if app.entra == 0 
                    app.InordertotrainthecodeyoufirstneedtoaddanexerciseoptionLabel.Visible = 'on';
                    app.WARNINGLabel.Visible = 'on';
                    app.entra = 1;
                end
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uiresume( app.UIFigure);
            delete(app)
            
        end

        % Value changed function: GotoMainmenuButton
        function GotoMainmenuButtonValueChanged(app, event)
            UIFigureCloseRequest(app, event);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CreatingTheExerciseOptionsLabel = uilabel(app.UIFigure);
            app.CreatingTheExerciseOptionsLabel.Text = 'Creating The Exercise Options ';
            app.InsertEditField = uieditfield(app.UIFigure, 'text');
            app.InsertTrainingOptionLabel = uilabel(app.UIFigure);
            app.InsertTrainingOptionLabel.Text = 'Insert Training Option';
            app.InordertoTrainthecodeyoufirstneedtocreatetheLabel = uilabel(app.UIFigure);
            app.InordertoTrainthecodeyoufirstneedtocreatetheLabel.Text = 'In order to create an exercise, first you need to add the options that the exercise should have';
            app.InsertinthefieldtheoptionandclickonaccepttosaveitLabel = uilabel(app.UIFigure);
            app.InsertinthefieldtheoptionandclickonaccepttosaveitLabel.Text = 'Insert in the field the option and click on "Add option"  to save it, just one at time ';
            app.OptionsselectedLabel = uilabel(app.UIFigure);
            app.OptionsselectedLabel.Text = 'Options selected';
            app.ListBox = uilistbox(app.UIFigure);
            app.AddoptionButton = uibutton(app.UIFigure, 'state');
            app.AddoptionButton.ValueChangedFcn = createCallbackFcn(app, @AddoptionButtonValueChanged, true);
            app.AddoptionButton.Text = 'Add option';
            app.GotoMainmenuButton = uibutton(app.UIFigure, 'state');
            app.GotoMainmenuButton.ValueChangedFcn = createCallbackFcn(app, @GotoMainmenuButtonValueChanged, true);
            app.GotoMainmenuButton.Text = 'Go to Main menu';
            app.StartTakingSamplesButton = uibutton(app.UIFigure, 'state');
            app.StartTakingSamplesButton.ValueChangedFcn = createCallbackFcn(app, @StartTakingSamplesButtonValueChanged, true);
            app.StartTakingSamplesButton.Text = 'Start Taking Samples';
            app.InordertotrainthecodeyoufirstneedtoaddanexerciseoptionLabel = uilabel(app.UIFigure);
            app.InordertotrainthecodeyoufirstneedtoaddanexerciseoptionLabel.Text = {'In order to start taking samples'; 'first you need to add at least '; '       one exercise option'};
            app.WARNINGLabel = uilabel(app.UIFigure);
            app.WARNINGLabel.Text = 'WARNING !!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Create_Options(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end