classdef GUI_Menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        WhatdoyouwanttodoLabel  matlab.ui.control.Label
        ViewthesensorsButton    matlab.ui.control.Button
        CreateanexerciseButton  matlab.ui.control.Button
        ViewtheresultButton     matlab.ui.control.Button
        ExittheprogramButton    matlab.ui.control.Button
        ThisbuttonwillopenainterfacewhereLabel  matlab.ui.control.Label
        ThisbuttonwillopenainterfacewhereLabel_2  matlab.ui.control.Label
        ThisbuttonwillopenainterfacewhereLabel_3  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ViewthesensorsButton
        function ViewthesensorsButtonPushed(app, event)
            assignin('base',"choise",1)
            UIFigureCloseRequest(app);
        end

        % Button pushed function: CreateanexerciseButton
        function CreateanexerciseButtonPushed(app, event)
            assignin('base',"choise",2)
            UIFigureCloseRequest(app);
        end

        % Button pushed function: ViewtheresultButton
        function ViewtheresultButtonPushed(app, event)
            assignin('base',"choise",3)
            UIFigureCloseRequest(app);
        end

        % Button pushed function: ExittheprogramButton
        function ExittheprogramButtonPushed(app, event)
            assignin('base',"choise",0)
            UIFigureCloseRequest(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.WhatdoyouwanttodoLabel = uilabel(app.UIFigure);
            app.WhatdoyouwanttodoLabel.Text = 'What do you want to do ?';
            app.ViewthesensorsButton = uibutton(app.UIFigure, 'push');
            app.ViewthesensorsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewthesensorsButtonPushed, true);
            app.ViewthesensorsButton.Text = 'View the sensors';
            app.CreateanexerciseButton = uibutton(app.UIFigure, 'push');
            app.CreateanexerciseButton.ButtonPushedFcn = createCallbackFcn(app, @CreateanexerciseButtonPushed, true);
            app.CreateanexerciseButton.Text = 'Create an exercise';
            app.ViewtheresultButton = uibutton(app.UIFigure, 'push');
            app.ViewtheresultButton.ButtonPushedFcn = createCallbackFcn(app, @ViewtheresultButtonPushed, true);
            app.ViewtheresultButton.Text = 'View the result';
            app.ExittheprogramButton = uibutton(app.UIFigure, 'push');
            app.ExittheprogramButton.ButtonPushedFcn = createCallbackFcn(app, @ExittheprogramButtonPushed, true);
            app.ExittheprogramButton.Text = 'Exit the program';
            app.ThisbuttonwillopenainterfacewhereLabel = uilabel(app.UIFigure);
            app.ThisbuttonwillopenainterfacewhereLabel.Text = {'This button will open an interface where'; 'you can visualize the sensors data in '; '4 different grapths'};
            app.ThisbuttonwillopenainterfacewhereLabel_2 = uilabel(app.UIFigure);
            app.ThisbuttonwillopenainterfacewhereLabel_2.Text = {'This button will open an interface that'; 'allows you to create a personalized '; 'exercise for a patient'};
            app.ThisbuttonwillopenainterfacewhereLabel_3 = uilabel(app.UIFigure);
            app.ThisbuttonwillopenainterfacewhereLabel_3.Text = {'This button will open an interface that'; 'allows you to choose between example '; 'exercices or use the one created by you'; 'and start viewing the result from the '; '                    patient input'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end