classdef GUI_Train < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ListBox               matlab.ui.control.ListBox
        WhichsampledoyouwanttotakeLabel  matlab.ui.control.Label
        TakeSampleButton      matlab.ui.control.Button
        GotomainmenuButton    matlab.ui.control.Button
        DoyouwanttodeleteyourlastsampletakenLabel  matlab.ui.control.Label
        YesButton             matlab.ui.control.Button
        YourlastsamplewasdeletedsuccesfullyLabel  matlab.ui.control.Label
        CreateexerciseButton  matlab.ui.control.Button
        WarningLabel          matlab.ui.control.Label
        InordertocreatetheexerciseatleasttheremustbeasampletakenLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        valores = 0; % Description
        index = 0;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, argumento, Index_data_adquisition)
             app.valores = argumento;
             app.ListBox.Items =  argumento;
             app.index = Index_data_adquisition;
             app.YourlastsamplewasdeletedsuccesfullyLabel.Visible = 'off';
             app.WarningLabel.Visible= 'off';
             app.InordertocreatetheexerciseatleasttheremustbeasampletakenLabel.Visible = 'off';
             if (Index_data_adquisition >= 1 )
                 app.YesButton.Visible = 'on';
                 app.DoyouwanttodeleteyourlastsampletakenLabel.Visible = 'on';
             else 
                 app.YesButton.Visible = 'off';
                 app.DoyouwanttodeleteyourlastsampletakenLabel.Visible = 'off';
             end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uiresume(app.UIFigure);
            delete(app)
            
        end

        % Button pushed function: TakeSampleButton
        function TakeSampleButtonPushed(app, event)
            seleccion =app.ListBox.Value;
            for i = 1 : length(app.valores)
                if strcmp(seleccion , app.valores(i))
                    assignin('base',"Index_data_adquisition",(app.index + 1))
                    assignin('base',"choise_Ytrain",i)
                end
            end
            assignin('base',"exit",0)
            UIFigureCloseRequest(app, event)
        end

        % Button pushed function: GotomainmenuButton
        function GotomainmenuButtonPushed(app, event)
            if (app.index <= 0)
                assignin('base',"exit",2)
            end
            UIFigureCloseRequest(app, event)
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            app.index = app.index-1;
            assignin('base',"Index_data_adquisition",app.index)
            app.YesButton.Visible = 'off';
            app.DoyouwanttodeleteyourlastsampletakenLabel.Visible = 'off';
            app.YourlastsamplewasdeletedsuccesfullyLabel.Visible = 'on';
        end

        % Button pushed function: CreateexerciseButton
        function CreateexerciseButtonPushed(app, event)
            if (app.index <= 0)
                app.WarningLabel.Visible= 'on';
                app.InordertocreatetheexerciseatleasttheremustbeasampletakenLabel.Visible = 'on';
            else
                assignin('base',"exit",1)
                UIFigureCloseRequest(app, event)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ListBox = uilistbox(app.UIFigure);
            app.WhichsampledoyouwanttotakeLabel = uilabel(app.UIFigure);
            app.WhichsampledoyouwanttotakeLabel.Text = 'Which sample do you want to take?';
            app.TakeSampleButton = uibutton(app.UIFigure, 'push');
            app.TakeSampleButton.ButtonPushedFcn = createCallbackFcn(app, @TakeSampleButtonPushed, true);
            app.TakeSampleButton.Text = 'Take Sample';
            app.GotomainmenuButton = uibutton(app.UIFigure, 'push');
            app.GotomainmenuButton.ButtonPushedFcn = createCallbackFcn(app, @GotomainmenuButtonPushed, true);
            app.GotomainmenuButton.Text = 'Go to main menu';
            app.DoyouwanttodeleteyourlastsampletakenLabel = uilabel(app.UIFigure);
            app.DoyouwanttodeleteyourlastsampletakenLabel.Text = {'Do you want to delete '; 'your last sample taken?'};
            app.YesButton = uibutton(app.UIFigure, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.YourlastsamplewasdeletedsuccesfullyLabel = uilabel(app.UIFigure);
            app.YourlastsamplewasdeletedsuccesfullyLabel.Text = 'Your last sample was deleted succesfully !!';
            app.CreateexerciseButton = uibutton(app.UIFigure, 'push');
            app.CreateexerciseButton.ButtonPushedFcn = createCallbackFcn(app, @CreateexerciseButtonPushed, true);
            app.CreateexerciseButton.Text = 'Create exercise';
            app.WarningLabel = uilabel(app.UIFigure);
            app.WarningLabel.Text = 'Warning !!';
            app.InordertocreatetheexerciseatleasttheremustbeasampletakenLabel = uilabel(app.UIFigure);
            app.InordertocreatetheexerciseatleasttheremustbeasampletakenLabel.Text = {'In order to create the exercise'; 'at least there must be a sample '; '                  taken'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Train(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end